classdef FTIR_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CurrentdataLabel               matlab.ui.control.Label
        CurrentdataTextArea            matlab.ui.control.TextArea
        DiffusionCoefficientm2sLabel   matlab.ui.control.Label
        DiffusionCoefficientm2sTextArea  matlab.ui.control.TextArea
        OtherparametersTextAreaLabel   matlab.ui.control.Label
        OtherparametersTextArea        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        GasLineFitTab                  matlab.ui.container.Tab
        SpectrumEditFieldLabel         matlab.ui.control.Label
        SpectrumEditField              matlab.ui.control.NumericEditField
        centerEditFieldLabel           matlab.ui.control.Label
        centerEditField                matlab.ui.control.NumericEditField
        wgEditFieldLabel               matlab.ui.control.Label
        wgEditField                    matlab.ui.control.NumericEditField
        wlEditFieldLabel               matlab.ui.control.Label
        wlEditField                    matlab.ui.control.NumericEditField
        a1EditFieldLabel               matlab.ui.control.Label
        a1EditField                    matlab.ui.control.NumericEditField
        a2EditFieldLabel               matlab.ui.control.Label
        a2EditField                    matlab.ui.control.NumericEditField
        a3EditFieldLabel               matlab.ui.control.Label
        a3EditField                    matlab.ui.control.NumericEditField
        c0EditFieldLabel               matlab.ui.control.Label
        c0EditField                    matlab.ui.control.NumericEditField
        c1EditFieldLabel               matlab.ui.control.Label
        c1EditField                    matlab.ui.control.NumericEditField
        InitalFitGuessLabel            matlab.ui.control.Label
        TestParametersButton           matlab.ui.control.Button
        DoFitButton                    matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        UIAxes3                        matlab.ui.control.UIAxes
        ExamineSpectraTab              matlab.ui.container.Tab
        PlotSpectraButton              matlab.ui.control.Button
        SampleVolumeLEditField_2Label  matlab.ui.control.Label
        SampleVolumeLEditField         matlab.ui.control.NumericEditField
        SpacerSizemEditField_2Label    matlab.ui.control.Label
        SpacerSizemEditField           matlab.ui.control.NumericEditField
        TimeIntervalsEditField_2Label  matlab.ui.control.Label
        TimeIntervalsEditField         matlab.ui.control.NumericEditField
        ExperimentalParametersLabel    matlab.ui.control.Label
        UseCustomRadiusCheckBox        matlab.ui.control.CheckBox
        CustomRadiusmEditFieldLabel    matlab.ui.control.Label
        CustomRadiusmEditField         matlab.ui.control.NumericEditField
        UIAxes2                        matlab.ui.control.UIAxes
        CurveFittingTab                matlab.ui.container.Tab
        FinalConcTextArea              matlab.ui.control.TextArea
        TryFitButton                   matlab.ui.control.Button
        SaturatedConcButtonGroup       matlab.ui.container.ButtonGroup
        SelectOneButton                matlab.ui.control.ToggleButton
        UseLastSpectrumButton          matlab.ui.control.ToggleButton
        GetFinalSpectrumButton         matlab.ui.control.ToggleButton
        InputCustomButton              matlab.ui.control.ToggleButton
        SeeFullFitCurveCheckBox        matlab.ui.control.CheckBox
        NmaxSpinner_2Label             matlab.ui.control.Label
        NmaxSpinner                    matlab.ui.control.Spinner
        LowerEditFieldLabel_2          matlab.ui.control.Label
        LowerEditFieldD                matlab.ui.control.NumericEditField
        UpperEditFieldLabel_2          matlab.ui.control.Label
        UpperEditFieldD                matlab.ui.control.NumericEditField
        DiffMinChangeEditField_2Label  matlab.ui.control.Label
        DiffMinChangeEditField         matlab.ui.control.NumericEditField
        DiffMaxChangeEditField_2Label  matlab.ui.control.Label
        DiffMaxChangeEditField         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_2     matlab.ui.control.Label
        StartPointEditFieldD           matlab.ui.control.NumericEditField
        RadialResolutionEditField_2Label  matlab.ui.control.Label
        RadialResolutionEditField      matlab.ui.control.NumericEditField
        RadiusLimitEditField_2Label    matlab.ui.control.Label
        RadiusLimitEditField           matlab.ui.control.NumericEditField
        TestGuessButton                matlab.ui.control.Button
        CustomFinalConcEditFieldLabel  matlab.ui.control.Label
        CustomFinalConcEditField       matlab.ui.control.NumericEditField
        ClearDataButton                matlab.ui.control.Button
        LoadNewDataButton              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data FTIRexperiment % Description
    end
    
    methods (Access = private)
        
        function output = displayData(app)
            output = ["You have "+size(app.data.data,2)+" spectra.";...
                "Sample Volume is "+app.data.volume+" μL.";...
                "Spacer Size is "+app.data.pathLength+" μm.";...
                "Radius is "+string(app.data.radius)+" μm."];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            [spectra,freq] = LoadSpectra();
            app.data = FTIRexperiment(spectra,freq(:,1),0,1,0,"");
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            clear app.data
            app.CurrentdataTextArea.Value = "You have no data.";
            cla(app.UIAxes)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            cla(app.UIAxes2)
            app.UIAxes2.XLimMode = 'auto';
            app.UIAxes2.YLimMode = 'auto';
            cla(app.UIAxes3)
            app.UIAxes3.XLimMode = 'auto';
            app.UIAxes3.YLimMode = 'auto';
            app.SampleVolumeLEditField.Value = 0;
            app.SpacerSizemEditField.Value = 0;
            app.TimeIntervalsEditField.Value = 0;
            app.SpectrumEditField.Value = 1;
            app.centerEditField.Value = 2339;
            app.wgEditField.Value = 1.9;
            app.wlEditField.Value = 1.7;
            app.a1EditField.Value = 1;
            app.a2EditField.Value = 0.07;
            app.a3EditField.Value = 0;
            app.c0EditField.Value = 0;
            app.c1EditField.Value = 1e-6;
            app.PlotSpectraButton.Enable = false;
            app.FinalConcTextArea.Value = "";
            app.TextArea.Value = "";
            app.DiffusionCoefficientm2sTextArea.Value = "";
            app.NmaxSpinner.Value = 0;
            app.RadialResolutionEditField.Value = 100;
            app.RadiusLimitEditField.Value = 5000;
            app.StartPointEditFieldD.Value = 1000;
            app.LowerEditFieldD.Value = 0;
            app.UpperEditFieldD.Value = 10000;
            app.DiffMaxChangeEditField.Value = 1;
            app.DiffMinChangeEditField.Value = 0;
            app.SeeFullFitCurveCheckBox.Value = false;
            app.SeeFullFitCurveCheckBox.Enable = "off";
            app.TryFitButton.Enable = "off";
            app.SaturatedConcButtonGroup.SelectedObject = app.SelectOneButton;
            app.TextArea.Value = "";
            app.OtherparametersTextArea.Value = "";
            app.CustomRadiusmEditField.Value = 0;
            app.UseCustomRadiusCheckBox.Value = false;
            app.CustomFinalConcEditField.Value = 0;
            app.CustomFinalConcEditField.Enable = 'off';
        end

        % Button pushed function: PlotSpectraButton
        function PlotSpectraButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            %find the indicies for the amount of spectra desired
            spectraIndicies = zeros(1,10);
            interval = ceil(size(app.data.data,2)/10);
            for ii = 0:10-1
                spectraIndicies(ii+1) = 1+(ii*interval);
            end
            if spectraIndicies(end) ~= size(app.data.data,2)
                spectraIndicies = [spectraIndicies size(app.data.data,2)];
            end
            
            for ii = spectraIndicies
                temp = app.data.fittedSpectra(ii).fobj;
                pf = co2GasLineFitFunction(app.data.fittedSpectra(ii).x,temp.center,temp.w_g,temp.w_l,...
                    temp.a1,temp.a2,0,0,0);
                plot(app.UIAxes2,app.data.fittedSpectra(ii).x,pf)
                hold(app.UIAxes2,'on')
            end
            hold(app.UIAxes2,'off')
            
            %converts data in f to vector of concentration values of CO2
            %for each spectrum, generates time axis, returns a plot of
            %concentration vs time
            %syntax: plotConcOverTime(f)
            plot(app.UIAxes,timeAxis(app.data),fittedConcOverTime(app.data),'color','blue');
        end

        % Value changed function: SampleVolumeLEditField
        function SampleVolumeLEditFieldValueChanged(app, event)
            app.data.volume = app.SampleVolumeLEditField.Value;
            if app.UseCustomRadiusCheckBox.Value == false
                app.data.radius = sqrt(app.data.volume*1e9/(pi*app.data.pathLength));
            end
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: SpacerSizemEditField
        function SpacerSizemEditFieldValueChanged(app, event)
            app.data.pathLength = app.SpacerSizemEditField.Value;
            if app.UseCustomRadiusCheckBox.Value == false
                app.data.radius = sqrt(app.data.volume*1e9/(pi*app.data.pathLength));
            end
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: TimeIntervalsEditField
        function TimeIntervalsEditFieldValueChanged(app, event)
            app.data.timeInterval = app.TimeIntervalsEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Callback function: SeeFullFitCurveCheckBox, TryFitButton
        function TryFitButtonPushed(app, event)
            cla(app.UIAxes)
            %get parameters ready
            t = timeAxis(app.data);
            y = fittedConcOverTime(app.data);
            A = app.data.radius;
            C = app.data.finalConc;
            nmax = app.NmaxSpinner.Value;
            rres = app.RadialResolutionEditField.Value;
            rlim = app.RadiusLimitEditField.Value;
            sigma = 993.7;
            sp = app.StartPointEditFieldD.Value;
            ub = app.UpperEditFieldD.Value;
            lb = app.LowerEditFieldD.Value;
            
            %set up options and type
            opts = fitoptions('Method','NonlinearLeastSquares',...
                'Lower',lb,'Upper',ub,'StartPoint',sp,...
                'Display','Iter');
            ft = fittype(@(D,t) diffusion(t,D,A,C,nmax,rres,rlim,sigma),...
                'independent',{'t'},'dependent','absorbance',...
                'coefficients',{'D'},...
                'options',opts);
            %set up structure for storing output
            out = struct('x',[],'ydata',[],'yfit',[],'res',[],...
                'fobj',[],'G',[],'O',[]);
            
            tic
            
            %do the fit
            [fobj,G,O] = fit(t',y',ft);
            
            toc
            
            %get results
            yfit = fobj(t);
            out.x = t;
            out.ydata = y;
            out.yfit = yfit;
            out.res = y - yfit;
            out.fobj = fobj;
            out.G = G;
            out.O = O;
            
            if out.O.exitflag < 1
                warning('Curve fit did not converge!!! Results might not be trustworthy.');
            end
            
            if app.SeeFullFitCurveCheckBox.Value == false
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                cla(app.UIAxes)
                plot(app.UIAxes,out.x,out.ydata,'o','MarkerSize',4)
                hold(app.UIAxes,'on')
                plot(app.UIAxes,out.x,out.yfit)
                hold(app.UIAxes,'off')
            elseif app.SeeFullFitCurveCheckBox.Value == true
                
            end
            
            % get confidence intervals
            ci = confint(out.fobj);
            stdev = (ci(2)-out.fobj.D)/2;
            pct = stdev/out.fobj.D;
            
            app.DiffusionCoefficientm2sTextArea.Value = string(out.fobj.D);
            app.OtherparametersTextArea.Value = ["95% CI: "+ci(1)+" - "+ci(2);...
                "St.Dev. = "+stdev+" ("+pct+"%)";...
                "R^2 = "+string(out.G.rsquare)];
            
            app.TryFitButton.Enable = "off";
            app.TestGuessButton.Enable = 'off';
            app.SeeFullFitCurveCheckBox.Enable = "on";
        end

        % Selection changed function: SaturatedConcButtonGroup
        function SaturatedConcButtonGroupSelectionChange(app, event)
            selectedButton = app.SaturatedConcButtonGroup.SelectedObject;
            app.CustomFinalConcEditField.Enable = 'off';
            app.CustomFinalConcEditField.Value = 0;
            if selectedButton.Text == "Use Last Spectrum"
                app.data.finalSpectrum = app.data.data(:,end);
                app.data = getFinalConc(app.data,app.centerEditField.Value,...
                app.wgEditField.Value,app.wlEditField.Value,app.a1EditField.Value,...
                app.a2EditField.Value,app.a3EditField.Value,app.c0EditField.Value,...
                app.c1EditField.Value);
            elseif selectedButton.Text == "Get Final Spectrum"
                app.data.finalSpectrum = LoadSpectra();
                app.data = getFinalConc(app.data,app.centerEditField.Value,...
                app.wgEditField.Value,app.wlEditField.Value,app.a1EditField.Value,...
                app.a2EditField.Value,app.a3EditField.Value,app.c0EditField.Value,...
                app.c1EditField.Value);
            elseif selectedButton.Text == "Input Custom"
                app.data.finalSpectrum = [];
                app.CustomFinalConcEditField.Enable = 'on';
                app.data.finalConc = 0;
            end
            app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
        end

        % Value changed function: DiffMaxChangeEditField, 
        % DiffMinChangeEditField, LowerEditFieldD, NmaxSpinner, 
        % RadialResolutionEditField, RadiusLimitEditField, 
        % StartPointEditFieldD, UpperEditFieldD
        function FitParameterChanged(app, event)
            app.TestGuessButton.Enable = 'on';
            app.TryFitButton.Enable = 'on';
            app.SeeFullFitCurveCheckBox.Enable = "off";
        end

        % Button pushed function: TestParametersButton
        function TestParametersButtonPushed(app, event)
            % have the user select which spectrum to guess from
            ii = app.SpectrumEditField.Value;
            
            % set the fit range
            range1 = [2290 2390];
            
            % set starting point using values from the user
            center = app.centerEditField.Value;
            wg = app.wgEditField.Value;
            wl = app.wlEditField.Value;
            a1 = app.a1EditField.Value;
            a2 = app.a2EditField.Value; % expected Boltzmann factor for bend
            a3 = app.a3EditField.Value; % gas lines
            c0 = app.c0EditField.Value; % baseline offset
            c1 = app.c1EditField.Value; % baseline slope
            
            %fit function requires fliipped inputs
            freq = flip(app.data.freqAxis);
            s = flip(app.data.data(:,ii));
            
            %get x and y data for the fit
            ind1 = find(freq>=range1(1) & freq<range1(2));
            x = freq(ind1);
            ydata = s(ind1);
            
            %plot the fitted function using user parameters
            yfit = co2GasLineFitFunction(x,center,wg,wl,a1,a2,a3,c0,c1);
            res = ydata-yfit;
            sse = sum(res.^2);
            
            cla(app.UIAxes3)
            plot(app.UIAxes3,x,ydata,'o',x,yfit,x,res-0.1,'r-o')
            %app.UIAxes3.Title = (sprintf('Initial guess SSE = %f',sse));
        end

        % Button pushed function: DoFitButton
        function DoFitButtonPushed(app, event)
            app.TextArea.Value = "Running...";
            T = tic; %time the fitting for later display
            app.data = gasLineFit(app.data,app.centerEditField.Value,app.wgEditField.Value,app.wlEditField.Value,...
                app.a1EditField.Value,app.a2EditField.Value,app.a3EditField.Value,app.c0EditField.Value,...
                app.c1EditField.Value);
            stop = toc(T);
            
            %selecte 4 evenly placed fits to plot
            n_spectra = size(app.data.data,2);
            iis = ceil([1 n_spectra/4 n_spectra*3/4 n_spectra]);
            cla(app.UIAxes3)
            for ii = iis
                plot(app.UIAxes3,app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).ydata,'o',...
                    app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).yfit,...
                    app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).res-0.1,'ro')
                hold(app.UIAxes3,'on')
            end
            hold(app.UIAxes3,'off')
            
            %let the user know how it went
            app.TextArea.Value = "";
            tl = 0;
            for ii = 1:n_spectra
                if app.data.fittedSpectra(ii).O.exitflag < 1
                    app.TextArea.Value = [app.TextAreaValue.Value;'Spectrum '+ii+' did not converge!!! Results might not be trustworthy.'];
                    tl = tl+1;
                end
            end
            if tl==0
                app.TextArea.Value = "All fits were successful.";
            end
            app.TextArea.Value = [app.TextArea.Value;"Fitting took "+stop+" seconds."];
        end

        % Button pushed function: TestGuessButton
        function TestGuessButtonPushed(app, event)
            sigma = 993.7;
            x = timeAxis(app.data);
            y = diffusion(x,app.StartPointEditFieldD.Value,app.data.radius,app.data.finalConc,...
                app.NmaxSpinner.Value,app.RadialResolutionEditField.Value,...
                app.RadiusLimitEditField.Value,sigma);
            cla(app.UIAxes)
            ydata = fittedConcOverTime(app.data);
            res = ydata-y';
            plot(app.UIAxes,x,ydata,'o','MarkerSize',4)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,x,y)
            plot(app.UIAxes,x,res-0.1,'ro','MarkerSize',4)
            hold(app.UIAxes,'off')
            app.TestGuessButton.Enable = 'off';
        end

        % Value changed function: UseCustomRadiusCheckBox
        function UseCustomRadiusCheckBoxValueChanged(app, event)
            value = app.UseCustomRadiusCheckBox.Value;
            if value == true
                app.CustomRadiusmEditField.Enable = 'on';
            elseif value == false
                app.CustomRadiusmEditField.Enable = 'off';
                app.data.radius = sqrt(app.data.volume*1e9/(pi*app.data.pathLength));
                app.CurrentdataTextArea.Value = displayData(app);
            end
        end

        % Value changed function: CustomRadiusmEditField
        function CustomRadiusmEditFieldValueChanged(app, event)
            value = app.CustomRadiusmEditField.Value;
            if app.UseCustomRadiusCheckBox.Value == true
                app.data.radius = value;
            end
            app.CurrentdataTextArea.Value = displayData(app);
        end

        % Value changed function: CustomFinalConcEditField
        function CustomFinalConcEditFieldValueChanged(app, event)
            value = app.CustomFinalConcEditField.Value;
            app.data.finalConc = value;
            app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
            FitParameterChanged(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentdataLabel = uilabel(app.UIFigure);
            app.CurrentdataLabel.Text = 'Current data:';
            app.CurrentdataTextArea = uitextarea(app.UIFigure);
            app.CurrentdataTextArea.Value = {'You have no data.'};
            app.DiffusionCoefficientm2sLabel = uilabel(app.UIFigure);
            app.DiffusionCoefficientm2sLabel.Text = 'Diffusion Coefficient (μm^2/s)';
            app.DiffusionCoefficientm2sTextArea = uitextarea(app.UIFigure);
            app.OtherparametersTextAreaLabel = uilabel(app.UIFigure);
            app.OtherparametersTextAreaLabel.Text = 'Other parameters';
            app.OtherparametersTextArea = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GasLineFitTab = uitab(app.TabGroup);
            app.GasLineFitTab.Title = 'Gas Line Fit';
            app.SpectrumEditFieldLabel = uilabel(app.GasLineFitTab);
            app.SpectrumEditFieldLabel.Text = 'Spectrum #';
            app.SpectrumEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.SpectrumEditField.Value = 1;
            app.centerEditFieldLabel = uilabel(app.GasLineFitTab);
            app.centerEditFieldLabel.Text = 'center';
            app.centerEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.centerEditField.ValueDisplayFormat = '%.1f';
            app.centerEditField.Value = 2339;
            app.wgEditFieldLabel = uilabel(app.GasLineFitTab);
            app.wgEditFieldLabel.Text = 'wg';
            app.wgEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.wgEditField.Value = 1.9;
            app.wlEditFieldLabel = uilabel(app.GasLineFitTab);
            app.wlEditFieldLabel.Text = 'wl';
            app.wlEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.wlEditField.Value = 1.7;
            app.a1EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.a1EditField.Value = 1;
            app.a2EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.a2EditField.Value = 0.07;
            app.a3EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c0EditFieldLabel = uilabel(app.GasLineFitTab);
            app.c0EditFieldLabel.Text = 'c0';
            app.c0EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c1EditFieldLabel = uilabel(app.GasLineFitTab);
            app.c1EditFieldLabel.Text = 'c1';
            app.c1EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c1EditField.Value = 1e-06;
            app.InitalFitGuessLabel = uilabel(app.GasLineFitTab);
            app.InitalFitGuessLabel.Text = 'Inital Fit Guess';
            app.TestParametersButton = uibutton(app.GasLineFitTab, 'push');
            app.TestParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TestParametersButtonPushed, true);
            app.TestParametersButton.Text = 'Test Parameters';
            app.DoFitButton = uibutton(app.GasLineFitTab, 'push');
            app.DoFitButton.ButtonPushedFcn = createCallbackFcn(app, @DoFitButtonPushed, true);
            app.DoFitButton.Text = 'Do Fit';
            app.TextArea = uitextarea(app.GasLineFitTab);
            app.UIAxes3 = uiaxes(app.GasLineFitTab);
            title(app.UIAxes3, 'FTIR Spectrum of the CO2 Peak')
            xlabel(app.UIAxes3, 'frequency (cm^{-1})')
            ylabel(app.UIAxes3, 'absorbance (AU)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [2290 2390];
            app.UIAxes3.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ExamineSpectraTab = uitab(app.TabGroup);
            app.ExamineSpectraTab.Title = 'Examine Spectra';
            app.PlotSpectraButton = uibutton(app.ExamineSpectraTab, 'push');
            app.PlotSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectraButtonPushed, true);
            app.PlotSpectraButton.Enable = 'off';
            app.PlotSpectraButton.Text = 'Plot Spectra';
            app.SampleVolumeLEditField_2Label = uilabel(app.ExamineSpectraTab);
            app.SampleVolumeLEditField_2Label.Text = 'Sample Volume (μL)';
            app.SampleVolumeLEditField = uieditfield(app.ExamineSpectraTab, 'numeric');
            app.SampleVolumeLEditField.ValueChangedFcn = createCallbackFcn(app, @SampleVolumeLEditFieldValueChanged, true);
            app.SpacerSizemEditField_2Label = uilabel(app.ExamineSpectraTab);
            app.SpacerSizemEditField_2Label.Text = 'Spacer Size (μm)';
            app.SpacerSizemEditField = uieditfield(app.ExamineSpectraTab, 'numeric');
            app.SpacerSizemEditField.ValueChangedFcn = createCallbackFcn(app, @SpacerSizemEditFieldValueChanged, true);
            app.TimeIntervalsEditField_2Label = uilabel(app.ExamineSpectraTab);
            app.TimeIntervalsEditField_2Label.Text = 'Time Interval (s)';
            app.TimeIntervalsEditField = uieditfield(app.ExamineSpectraTab, 'numeric');
            app.TimeIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervalsEditFieldValueChanged, true);
            app.ExperimentalParametersLabel = uilabel(app.ExamineSpectraTab);
            app.ExperimentalParametersLabel.Text = 'Experimental Parameters';
            app.UseCustomRadiusCheckBox = uicheckbox(app.ExamineSpectraTab);
            app.UseCustomRadiusCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCustomRadiusCheckBoxValueChanged, true);
            app.UseCustomRadiusCheckBox.Text = 'Use Custom Radius';
            app.CustomRadiusmEditFieldLabel = uilabel(app.ExamineSpectraTab);
            app.CustomRadiusmEditFieldLabel.Enable = 'off';
            app.CustomRadiusmEditFieldLabel.Text = 'Custom Radius (μm)';
            app.CustomRadiusmEditField = uieditfield(app.ExamineSpectraTab, 'numeric');
            app.CustomRadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @CustomRadiusmEditFieldValueChanged, true);
            app.CustomRadiusmEditField.Enable = 'off';
            app.UIAxes2 = uiaxes(app.ExamineSpectraTab);
            title(app.UIAxes2, '*Fitted* FTIR Spectra of the CO2 Peak')
            xlabel(app.UIAxes2, 'frequency (cm^{-1})')
            ylabel(app.UIAxes2, 'absorbance (AU)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [2290 2390];
            app.CurveFittingTab = uitab(app.TabGroup);
            app.CurveFittingTab.Title = 'Curve Fitting';
            app.FinalConcTextArea = uitextarea(app.CurveFittingTab);
            app.FinalConcTextArea.Value = {''; ''};
            app.TryFitButton = uibutton(app.CurveFittingTab, 'push');
            app.TryFitButton.ButtonPushedFcn = createCallbackFcn(app, @TryFitButtonPushed, true);
            app.TryFitButton.Enable = 'off';
            app.TryFitButton.Text = 'Try Fit';
            app.SaturatedConcButtonGroup = uibuttongroup(app.CurveFittingTab);
            app.SaturatedConcButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SaturatedConcButtonGroupSelectionChange, true);
            app.SaturatedConcButtonGroup.Title = 'Saturated Conc';
            app.SelectOneButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.SelectOneButton.Text = 'Select One';
            app.SelectOneButton.Value = true;
            app.UseLastSpectrumButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.UseLastSpectrumButton.Text = 'Use Last Spectrum';
            app.GetFinalSpectrumButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.GetFinalSpectrumButton.Text = 'Get Final Spectrum';
            app.InputCustomButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.InputCustomButton.Text = 'Input Custom';
            app.SeeFullFitCurveCheckBox = uicheckbox(app.CurveFittingTab);
            app.SeeFullFitCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @TryFitButtonPushed, true);
            app.SeeFullFitCurveCheckBox.Enable = 'off';
            app.SeeFullFitCurveCheckBox.Text = 'See Full Fit Curve';
            app.NmaxSpinner_2Label = uilabel(app.CurveFittingTab);
            app.NmaxSpinner_2Label.Text = 'Nmax';
            app.NmaxSpinner = uispinner(app.CurveFittingTab);
            app.NmaxSpinner.Limits = [0 1000];
            app.NmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.LowerEditFieldLabel_2 = uilabel(app.CurveFittingTab);
            app.LowerEditFieldLabel_2.Text = 'Lower';
            app.LowerEditFieldD = uieditfield(app.CurveFittingTab, 'numeric');
            app.LowerEditFieldD.Limits = [0 Inf];
            app.LowerEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UpperEditFieldLabel_2 = uilabel(app.CurveFittingTab);
            app.UpperEditFieldLabel_2.Text = 'Upper';
            app.UpperEditFieldD = uieditfield(app.CurveFittingTab, 'numeric');
            app.UpperEditFieldD.Limits = [0 Inf];
            app.UpperEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UpperEditFieldD.Value = 10000;
            app.DiffMinChangeEditField_2Label = uilabel(app.CurveFittingTab);
            app.DiffMinChangeEditField_2Label.Text = 'DiffMinChange';
            app.DiffMinChangeEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.DiffMinChangeEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.DiffMaxChangeEditField_2Label = uilabel(app.CurveFittingTab);
            app.DiffMaxChangeEditField_2Label.Text = 'DiffMaxChange';
            app.DiffMaxChangeEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.DiffMaxChangeEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.DiffMaxChangeEditField.Value = 1;
            app.StartPointEditFieldLabel_2 = uilabel(app.CurveFittingTab);
            app.StartPointEditFieldLabel_2.Text = 'Start Point';
            app.StartPointEditFieldD = uieditfield(app.CurveFittingTab, 'numeric');
            app.StartPointEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.StartPointEditFieldD.Value = 1000;
            app.RadialResolutionEditField_2Label = uilabel(app.CurveFittingTab);
            app.RadialResolutionEditField_2Label.Text = 'Radial Resolution';
            app.RadialResolutionEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.RadialResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.RadialResolutionEditField.Value = 100;
            app.RadiusLimitEditField_2Label = uilabel(app.CurveFittingTab);
            app.RadiusLimitEditField_2Label.Text = 'Radius Limit';
            app.RadiusLimitEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.RadiusLimitEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.RadiusLimitEditField.Value = 5000;
            app.TestGuessButton = uibutton(app.CurveFittingTab, 'push');
            app.TestGuessButton.ButtonPushedFcn = createCallbackFcn(app, @TestGuessButtonPushed, true);
            app.TestGuessButton.Enable = 'off';
            app.TestGuessButton.Text = 'Test Guess';
            app.CustomFinalConcEditFieldLabel = uilabel(app.CurveFittingTab);
            app.CustomFinalConcEditFieldLabel.Enable = 'off';
            app.CustomFinalConcEditFieldLabel.Text = 'Custom Final Conc';
            app.CustomFinalConcEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.CustomFinalConcEditField.ValueChangedFcn = createCallbackFcn(app, @CustomFinalConcEditFieldValueChanged, true);
            app.CustomFinalConcEditField.Enable = 'off';
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.LoadNewDataButton = uibutton(app.UIFigure, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration of CO2 vs Time')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'concentration (M)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FTIR_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end